classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GradesManage                    matlab.ui.Figure
        Radar                           matlab.ui.control.UIAxes
        Score                           matlab.ui.control.UIAxes
        STARTButton_F                   matlab.ui.control.StateButton
        StudentIDEditField1             matlab.ui.control.NumericEditField
        Welcome                         matlab.ui.control.TextArea
        I_am                            matlab.ui.control.Label
        FreshmanButton                  matlab.ui.control.Button
        JuniorSeniorButton              matlab.ui.control.Button
        TeacherButton                   matlab.ui.control.Button
        PleasetypeinyourstudentIDLabel  matlab.ui.control.Label
        CourseSelect_text               matlab.ui.control.TextArea
        RecommendedCourseselectionPlanLabel  matlab.ui.control.Label
        BACKButton                      matlab.ui.control.StateButton
        STARTButton_JS                  matlab.ui.control.StateButton
        StudentIDEditField2             matlab.ui.control.NumericEditField
        Comfort_text                    matlab.ui.control.TextArea
        fighting                        matlab.ui.control.Image
        stability                       matlab.ui.control.Label
        average                         matlab.ui.control.Label
        previous                        matlab.ui.control.Label
        predicted                       matlab.ui.control.Label
        university                      matlab.ui.control.TextArea
        grade                           matlab.ui.control.EditField
        gradeChoosingLabel              matlab.ui.control.Label
        subjectChoosing                 matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ChineseButton                   matlab.ui.control.ToggleButton
        MathButton                      matlab.ui.control.ToggleButton
        EnglishButton                   matlab.ui.control.ToggleButton
        PhysicsButton                   matlab.ui.control.ToggleButton
        ChemistryButton                 matlab.ui.control.ToggleButton
        BiologyButton                   matlab.ui.control.ToggleButton
        HistoryButton                   matlab.ui.control.ToggleButton
        GeographyButton                 matlab.ui.control.ToggleButton
        PoliticsButton                  matlab.ui.control.ToggleButton
        TeacherStart                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        progress                        matlab.ui.control.TextArea
        decline                         matlab.ui.control.TextArea
        StudentswithGreatProgressLabel  matlab.ui.control.Label
        StudentswithDeclineLabel        matlab.ui.control.Label
        DetailedScoreButton             matlab.ui.control.Button
        detail                          matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % the global variable is used as the storage of the data from
            % all the files
            global directory ;
            directory = uigetdir('','select the path：');
            
            % the following codes just set the useless object in the start scene invisible
            app.DetailedScoreButton.Visible='off';
            app.Radar.Visible='off';
            app.Score.Visible='off';
            app.STARTButton_F.Visible='off';
            app.StudentIDEditField1.Visible='off';
            app.FreshmanButton.Visible='off';
            app.JuniorSeniorButton.Visible='off';
            app.TeacherButton.Visible='off';
            app.RecommendedCourseselectionPlanLabel.Visible='off';
            app.STARTButton_JS.Visible='off';
            app.StudentIDEditField2.Visible='off';
            app.BACKButton.Visible='off';
            app.Comfort_text.Visible='off';
            app.stability.Visible='off';
            app.average.Visible='off';
            app.fighting.Visible='off';
            app.previous.Visible='off';
            app.predicted.Visible='off';
            app.university.Visible='off';
            app.I_am.FontColor=[1.0 1.0 1.0];
            app.PleasetypeinyourstudentIDLabel.Visible='off';
            app.CourseSelect_text.Visible='off';
            app.university.Visible='off';
            app.gradeChoosingLabel.Visible='off';
            app.grade.Visible='off';
            app.subjectChoosing.Visible='off';
            app.ButtonGroup.Visible='off';
            app.UITable.Visible='off';
            app.progress.Visible='off';
            app.decline.Visible = 'off';
            app.TeacherStart.Visible='off';
            app.StudentswithDeclineLabel.Visible='off';
            app.StudentswithGreatProgressLabel.Visible='off';
            app.detail.Visible='off';
            
            % the animation of the start screen
            for i=0:0.3:90
                 app.Welcome.Position=[209 228+i 382 123];
                 pause(0.005);
            end
            for i=0:0.01:0.5
                app.I_am.FontColor=[1-i 1-i 1-i];
                pause(0.005);
            end
            pause(1);
            app.FreshmanButton.Visible='on';
            pause(1);
            app.JuniorSeniorButton.Visible='on';
            pause(1);
            app.TeacherButton.Visible='on';
            % reference of the picture: 
            % https://cn.bing.com/images/search?view=detailV2&ccid=9AHQsJMq&id=B8468BD5FC2A4317395D5FCAC2387753046E8AC1&thid=OIP.9AHQsJMqvSJEx1K5KGfhtgAAAA&mediaurl=http%3a%2f%2f5b0988e595225.cdn.sohucs.com%2fimages%2f20170831%2f02e8a4ebae114634adb984df1d614140.jpeg&exph=274&expw=455&q=%e5%8a%a0%e6%b2%b9+fighting&simid=608049781478985064&ck=DFA0BBB28E0735D237E7BEDA6C9ECED1&selectedindex=8&ajaxhist=0&first=1&scenario=ImageBasicHover
        end

        % Value changed function: STARTButton_F
        function STARTButton_FValueChanged(app, event)
        global directory;
        % open the visibility of the objects that are to show in frenshmen
        % page
        value = app.StudentIDEditField1.Value;
        app.STARTButton_F.Visible='off';
        app.StudentIDEditField1.Visible='off';
        app.PleasetypeinyourstudentIDLabel.Visible='off';
        axis(app.Radar,'equal');
        axis(app.Radar,[-12 12 -12 12]);
        app.Radar.Position=[34 282 387 243];
        app.Score.Position=[34 31 380 243];

        hold(app.Radar,'on');
        hold(app.Score,'on');
        
        %read the data of corresponding student
        [~,~,~,phy,che,bio,pol,his,geo,~,total,~,~,~]=readdata(value, directory);
        app.Radar.Visible='on';
        app.Score.Visible='on';
        app.CourseSelect_text.Visible='on';
        app.RecommendedCourseselectionPlanLabel.Visible='on';
        axis(app.Radar,'off');
   
       total = transpose(total(:,7));
       axis(app.Score,[0 numel(total)+1 0 100]);
       
       grades = [mean(phy(:,7)),mean(che(:,7)),mean(bio(:,7)),mean(his(:,7)),...
           mean(geo(:,7)),mean(pol(:,7))];
       % draw the 3 main lines in the radar 
       x1 = [0 0];  
       y1 = [-10,10];
       x2 = [-5*sqrt(3),5*sqrt(3)];
       y2 = [5,-5];
       x3 = [-5*sqrt(3),5*sqrt(3)];
       y3 = [-5,5];
       plot(app.Radar,x1,y1,'k-','LineWidth',2);
       plot(app.Radar,x2,y2,'k-','LineWidth',2);
       plot(app.Radar,x3,y3,'k-','LineWidth',2);
       
       % draw the dash lines between the main lines
       for i=2:2:10
           x_1 = [0,sqrt(3)/2*i];
           y_1 = [i,i/2];
           x_2 = [sqrt(3)/2*i,sqrt(3)/2*i];
           y_2 = [i/2,-i/2];
           x_3 = [sqrt(3)/2*i,0];
           y_3 = [-i/2,-i];
           x_4 = [0,-sqrt(3)/2*i];
           y_4 = [-i,-i/2];
           x_5 = [-sqrt(3)/2*i,-sqrt(3)/2*i];
           y_5 = [-i/2,i/2];
           x_6 = [-sqrt(3)/2*i,0];
           y_6 = [i/2,i];
           plot(app.Radar,x_1,y_1,':k','LineWidth',1);
           plot(app.Radar,x_2,y_2,':k','LineWidth',1);
           plot(app.Radar,x_3,y_3,':k','LineWidth',1);
           plot(app.Radar,x_4,y_4,':k','LineWidth',1);
           plot(app.Radar,x_5,y_5,':k','LineWidth',1);
           plot(app.Radar,x_6,y_6,':k','LineWidth',1);
       end
       
       % label the grades on the corresponding dash lines
       for i=2:2:10
           for theta = pi/6:pi/3:11*pi/6
               text(app.Radar,i*cos(theta)+0.1,i*sin(theta),num2str(i*10),'FontSize',6);
           end
       end
       
       % label the six axes with corresponding subjects
       text(app.Radar,5*sqrt(3)+0.5,(5*sqrt(3)+0.5)/sqrt(3)+0.5,'Physics');
       text(app.Radar,5*sqrt(3)+0.5,-(5*sqrt(3)+0.5)/sqrt(3)-0.5,'Chemistry');
       text(app.Radar,-1,-11,'Biology');
       text(app.Radar,-5*sqrt(3)-1.5,-(5*sqrt(3)+0.5)/sqrt(3)-0.5,'History');
       text(app.Radar,-5*sqrt(3)-3.5,(5*sqrt(3)+0.5)/sqrt(3)+0.5,'Geography');
       text(app.Radar,-1,11,'Politics');
       
       % draw the radar according to specific grades
       x_physics = grades(1)/10*cos(pi/6);
       y_physics = grades(1)/10*sin(pi/6);
       x_chemistry = grades(2)/10*cos(-pi/6);
       y_chemistry = grades(2)/10*sin(-pi/6);
       x_biology = 0;
       y_biology = -grades(3)/10;
       x_history = grades(4)/10*cos(7*pi/6);
       y_history = grades(4)/10*sin(7*pi/6);
       x_geography = grades(5)/10*cos(5*pi/6);
       y_geography = grades(5)/10*sin(5*pi/6);
       x_politics = 0;
       y_politics = grades(6)/10;
       x_fill = [x_physics,x_chemistry,x_biology,x_history,x_geography,x_politics];
       y_fill = [y_physics,y_chemistry,y_biology,y_history,y_geography,y_politics];
       app.stability.Visible='on';
       app.average.Visible='on';
       fill(app.Radar,x_fill, y_fill,[0 0.2 0.8]);
       alpha(app.Radar,0.4);
       
        selected_rank=[phy(:,7) che(:,7),bio(:,7),his(:,7)...
            ,geo(:,7),pol(:,7)];
        close all;
        var_rank=[var(selected_rank(:,1)),var(selected_rank(:,2)),var(selected_rank(:,3)),...
            var(selected_rank(:,4)),var(selected_rank(:,5)),var(selected_rank(:,6))];
        sqrt_rank=sqrt(var_rank);
        sqrt_rank=sqrt_rank./(max(sqrt_rank)).*100; 
        x_physics = sqrt_rank(1)/10*cos(pi/6);
       y_physics = sqrt_rank(1)/10*sin(pi/6);
       x_chemistry = sqrt_rank(2)/10*cos(-pi/6);
       y_chemistry = sqrt_rank(2)/10*sin(-pi/6);
       x_biology = 0;
       y_biology = -sqrt_rank(3)/10;
       x_history = sqrt_rank(4)/10*cos(7*pi/6);
       y_history = sqrt_rank(4)/10*sin(7*pi/6);
       x_geography = sqrt_rank(5)/10*cos(5*pi/6);
       y_geography = sqrt_rank(5)/10*sin(5*pi/6);
       x_politics = 0;
       y_politics = sqrt_rank(6)/10;
       x_fill = [x_physics,x_chemistry,x_biology,x_history,x_geography,x_politics];
       y_fill = [y_physics,y_chemistry,y_biology,y_history,y_geography,y_politics];
        fill(app.Radar,x_fill, y_fill, [0.5 0 0.2]);
        alpha(app.Radar,0.4);
        
        % type out the course-selection plan
        course_total_evaluation=grades.*0.7+sqrt_rank.*0.3;
        [~,I]=sort(course_total_evaluation);
        course_text='';
        for i=1:3
                switch I(i)
                    case 1
                        course_text=[course_text 'Physics '];
                    case 2
                        course_text=[course_text 'Chemistry '];
                    case 3
                        course_text=[course_text 'Biology '];
                    case 4
                        course_text=[course_text 'History '];
                    case 5
                        course_text=[course_text 'Geography '];
                    otherwise
                        course_text=[course_text 'Politics '];
                end
        end
        app.CourseSelect_text.Value = course_text;
        
        % find the abnormal grades of the student
        abnormal_low=[];
        abnormal_high=[];
        if total(2)-total(1)>0.3*total(1) 
            abnormal_high = [abnormal_high 2];
        elseif total(2)-total(1)<-0.3*total(1)
            abnormal_low=[abnormal_low 2];
        end
        for i=3:numel(total)
            Mean = mean(total(1:i-1));
            Var = var(total(1:i-1));
            if total(i)-Mean<-2*Var || total(i)-total(i-1)<-0.2*total(i-1)
                abnormal_low = [abnormal_low i];
            elseif total(i)-Mean>2*Var || total(i)-total(i-1)>0.2*total(i-1)
                abnormal_high = [abnormal_high i];
            end
        end
        plot(app.Score,1:numel(total),total,'-o','Color',[0 0.2 0.8],'linewidth',1.5 ,'markersize',2.5);
        
        % point our the abnormal grades of the student and implement the "comfort system"
        if numel(abnormal_low)~=0 
            for i=1:numel(abnormal_low)
                plot(app.Score,abnormal_low(i),total(abnormal_low(i)),'o','MarkerSize',8,'MarkerEdgeColor','r','MarkerFaceColor',[1 .6 .6]);
            end
            app.fighting.Position=[449,117,284,146];
            app.fighting.Visible='on';
        else
            app.Comfort_text.Position=[449,117,284,146];
            app.Comfort_text.Visible='on';
        end
        if numel(abnormal_high)~=0 
            for i=1:numel(abnormal_low)
                plot(app.Score,abnormal_high(i),total(abnormal_high(i)),'o','MarkerSize',8,'MarkerEdgeColor','g','MarkerFaceColor',[0 0.5 0.2]);
            end
        end
        end

        % Button pushed function: FreshmanButton
        function FreshmanButtonPushed(app, event)
            % open the visibility of corresponding objects in freshmen page
            app.I_am.Visible='off';
            app.FreshmanButton.Visible='off';
            app.JuniorSeniorButton.Visible='off';
            app.TeacherButton.Visible='off';
            app.Welcome.Visible='off';
            app.StudentIDEditField1.Visible='on';
            app.STARTButton_F.Visible='on';
            app.STARTButton_JS.Visible='off';
            app.StudentIDEditField2.Visible='off';
            app.PleasetypeinyourstudentIDLabel.Visible='on';
            app.BACKButton.Visible='on';
        end

        % Value changed function: BACKButton
        function BACKButtonValueChanged(app, event)
            value = app.BACKButton.Value;
            % set the visibility of all the objects except the starting
            % page to "off"
            hold(app.Radar,'off');
            plot(app.Radar,1,1,'Color',[1 1 1]);
            hold(app.Score,'off');
            plot(app.Score,1,1,'Color',[1 1 1]);
            app.Radar.Visible='off';
            app.Score.Visible='off';
            app.STARTButton_F.Visible='off';
            app.StudentIDEditField1.Visible='off';
            app.PleasetypeinyourstudentIDLabel.Visible='off';
            app.CourseSelect_text.Visible='off';
            app.RecommendedCourseselectionPlanLabel.Visible='off';
            app.FreshmanButton.Visible='on';
            app.JuniorSeniorButton.Visible='on';
            app.TeacherButton.Visible='on';
            app.I_am.Visible='on';
            app.I_am.Visible='on';
            app.Welcome.Visible='on';
            app.STARTButton_JS.Visible='off';
            app.StudentIDEditField2.Visible='off';
            app.fighting.Visible='off';
            app.Comfort_text.Visible='off';
            app.stability.Visible='off';
            app.average.Visible='off';
            app.previous.Visible='off';
            app.predicted.Visible='off';
            app.university.Visible='off';
            app.gradeChoosingLabel.Visible='off';
            app.grade.Visible='off';
            app.subjectChoosing.Visible='off';
            app.ButtonGroup.Visible='off';
            app.UITable.Visible='off';
            app.progress.Visible='off';
            app.decline.Visible = 'off';
            app.TeacherStart.Visible='off';
            app.StudentswithDeclineLabel.Visible='off';
            app.StudentswithGreatProgressLabel.Visible='off';
            app.BACKButton.Visible='off';
            app.DetailedScoreButton.Visible='off';
            app.detail.Visible='off';
        end

        % Button pushed function: JuniorSeniorButton
        function JuniorSeniorButtonPushed(app, event)
            % set the visibility of the objects that are useless in
            % junior/senior student page
            app.I_am.Visible='off';
            app.FreshmanButton.Visible='off';
            app.JuniorSeniorButton.Visible='off';
            app.TeacherButton.Visible='off';
            app.Welcome.Visible='off';
            app.StudentIDEditField1.Visible='off';
            app.STARTButton_F.Visible='off';
            app.STARTButton_JS.Visible='on';
            app.StudentIDEditField2.Visible='on';
            app.PleasetypeinyourstudentIDLabel.Visible='on';
            app.BACKButton.Visible='on';
        end

        % Value changed function: STARTButton_JS
        function STARTButton_JSValueChanged(app, event)
            global directory;
            value = app.StudentIDEditField2.Value;
        % change the visibility
        app.STARTButton_F.Visible='off';
        app.StudentIDEditField1.Visible='off';
        app.PleasetypeinyourstudentIDLabel.Visible='off';
        app.STARTButton_JS.Visible='off';
        app.StudentIDEditField2.Visible='off';
        axis(app.Radar,'equal');
        axis(app.Radar,[-12 12 -12 12]);
        app.Score.Position=[34,40,380,243];
        app.DetailedScoreButton.Visible='on';
        

        hold(app.Score,'on');
        hold(app.Radar,'on');
        
        % read the data
        [chi,mat,eng,phy,che,bio,pol,his,geo,~,total,~,~,prediction]=readdata(value, directory);
        row = size(phy,1);
        
        % find the shortboard subjects
        judge(1) = phy(row,2);
        judge(2) = che(row,2);
        judge(3) = bio(row,2);
        judge(4) = pol(row,2);
        judge(5) = his(row,2);
        judge(6) = geo(row,2);
        [~,I]=sort(judge);
        selected=[0 0 0];
        selected_name={' ', ' ', ' '};
        for i=1:3
            switch I(7-i)
                case 1
                    selected(i)=mean(phy(:,7));
                    selected_name{i} = 'Physics';
                case 2
                    selected(i)=mean(che(:,7));
                    selected_name{i} = 'Chemistry';
                case 3
                    selected(i)=mean(bio(:,7));
                    selected_name{i} = 'Biology';
                case 4
                    selected(i)=mean(pol(:,7));
                    selected_name{i} = 'Politics';
                case 5
                    selected(i)=mean(his(:,7));
                    selected_name{i} = 'History';
                otherwise
                    selected(i)=mean(geo(:,7));
                    selected_name{i} = 'Geography';
            end
        end
        app.Score.Visible='on';
        app.Radar.Visible='on';
        axis(app.Radar,'off');
        
        grades = [mean(chi(:,7)),mean(mat(:,7)),mean(eng(:,7)),selected];
        
        [sorted_grades,I]=sort(grades);
        notGood_subjects={' '};
        if mean(sorted_grades)-sorted_grades(2)>0.2*mean(sorted_grades)
            switch I(2)
                case 1
                    notGood_subjects{1} = 'Chinese';
                case 2
                    notGood_subjects{1} = 'Math';
                case 3
                    notGood_subjects{1} = 'English';
                case 4
                    notGood_subjects{1} = selected_name{1};
                case 5
                    notGood_subjects{1} = selected_name{2};
                otherwise
                    notGood_subjects{1} = selected_name{3};
            end
            switch I(1)
                case 1
                    notGood_subjects{2} = 'Chinese';
                case 2
                    notGood_subjects{2} = 'Math';
                case 3
                    notGood_subjects{2} = 'English';
                case 4
                    notGood_subjects{2} = selected_name{1};
                case 5
                    notGood_subjects{2} = selected_name{2};
                otherwise
                    notGood_subjects{2} = selected_name{3};
            end
        elseif mean(sorted_grades)-sorted_grades(1)>0.2*mean(sorted_grades)
            switch I(1)
                case 1
                    notGood_subjects{2} = 'Chinese';
                case 2
                    notGood_subjects{2} = 'Math';
                case 3
                    notGood_subjects{2} = 'English';
                case 4
                    notGood_subjects{2} = selected_name{1};
                case 5
                    notGood_subjects{2} = selected_name{2};
                otherwise
                    notGood_subjects{2} = selected_name{3};
            end
        end
        if notGood_subjects{1}==' '
            app.CourseSelect_text.Visible='on';
            app.CourseSelect_text.Value='Based on your current performance, you do not have a short board. Keep studying hard!';
        elseif numel(notGood_subjects)==2
            app.CourseSelect_text.Visible='on';
            app.CourseSelect_text.Value=['You are not good at ',notGood_subjects{1},' and ',...
                notGood_subjects{2}];
        else
            app.CourseSelect_text.Visible='on';
            app.CourseSelect_text.Value=['You are not good at ',notGood_subjects];
        end
       
       % draw the radar 
       % draw the 3 main lines in the radar 
       x1 = [0 0];  
       y1 = [-10,10];
       x2 = [-5*sqrt(3),5*sqrt(3)];
       y2 = [5,-5];
       x3 = [-5*sqrt(3),5*sqrt(3)];
       y3 = [-5,5];
       plot(app.Radar,x1,y1,'k-','LineWidth',2);
       plot(app.Radar,x2,y2,'k-','LineWidth',2);
       plot(app.Radar,x3,y3,'k-','LineWidth',2);
       
       % draw the dash lines between the main lines
       for i=2:2:10
           x_1 = [0,sqrt(3)/2*i];
           y_1 = [i,i/2];
           x_2 = [sqrt(3)/2*i,sqrt(3)/2*i];
           y_2 = [i/2,-i/2];
           x_3 = [sqrt(3)/2*i,0];
           y_3 = [-i/2,-i];
           x_4 = [0,-sqrt(3)/2*i];
           y_4 = [-i,-i/2];
           x_5 = [-sqrt(3)/2*i,-sqrt(3)/2*i];
           y_5 = [-i/2,i/2];
           x_6 = [-sqrt(3)/2*i,0];
           y_6 = [i/2,i];
           plot(app.Radar,x_1,y_1,':k','LineWidth',1);
           plot(app.Radar,x_2,y_2,':k','LineWidth',1);
           plot(app.Radar,x_3,y_3,':k','LineWidth',1);
           plot(app.Radar,x_4,y_4,':k','LineWidth',1);
           plot(app.Radar,x_5,y_5,':k','LineWidth',1);
           plot(app.Radar,x_6,y_6,':k','LineWidth',1);
       end
       
       % label the grades on the corresponding dash lines
       for i=2:2:10
           for theta = pi/6:pi/3:11*pi/6
               text(app.Radar,i*cos(theta)+0.1,i*sin(theta),num2str(i*10),'FontSize',6);
           end
       end
       
       % label the six axes with corresponding subjects
       text(app.Radar,5*sqrt(3)+0.5,(5*sqrt(3)+0.5)/sqrt(3)+0.5,'Chinese');
       text(app.Radar,5*sqrt(3)+0.5,-(5*sqrt(3)+0.5)/sqrt(3)-0.5,'Math');
       text(app.Radar,-1,-11,'English');
       text(app.Radar,-5*sqrt(3)-1.5,-(5*sqrt(3)+0.5)/sqrt(3)-0.5,selected_name{1});
       text(app.Radar,-5*sqrt(3)-3.5,(5*sqrt(3)+0.5)/sqrt(3)+0.5,selected_name{2});
       text(app.Radar,-1,11,selected_name{3});
       
       % draw the radar according to specific grades
       x_physics = grades(1)/10*cos(pi/6);
       y_physics = grades(1)/10*sin(pi/6);
       x_chemistry = grades(2)/10*cos(-pi/6);
       y_chemistry = grades(2)/10*sin(-pi/6);
       x_biology = 0;
       y_biology = -grades(3)/10;
       x_history = grades(4)/10*cos(7*pi/6);
       y_history = grades(4)/10*sin(7*pi/6);
       x_geography = grades(5)/10*cos(5*pi/6);
       y_geography = grades(5)/10*sin(5*pi/6);
       x_politics = 0;
       y_politics = grades(6)/10;
       x_fill = [x_physics,x_chemistry,x_biology,x_history,x_geography,x_politics];
       y_fill = [y_physics,y_chemistry,y_biology,y_history,y_geography,y_politics];
       fill(app.Radar,x_fill, y_fill,[0 0.2 0.8]);
       alpha(app.Radar,0.4);
       
       % find the abnormal grades and point them out
       total = transpose(total(:,7));
       axis(app.Score,[0 numel(total)+4 0 100]);
        abnormal_low=[];
        abnormal_high=[];
        if total(2)-total(1)>0.3*total(1) 
            abnormal_high = [abnormal_high 2];
        elseif total(2)-total(1)<-0.3*total(1)
            abnormal_low=[abnormal_low 2];
        end
        for i=3:numel(total)
            Mean = mean(total(1:i-1));
            Var = var(total(1:i-1));
            if total(i)-Mean<-2*Var || total(i)-total(i-1)<-0.2*total(i-1)
                abnormal_low = [abnormal_low i];
            elseif total(i)-Mean>2*Var || total(i)-total(i-1)>0.2*total(i-1)
                abnormal_high = [abnormal_high i];
            end
        end
        plot(app.Score,1:numel(total),total,'-o','Color',[0 0.2 0.8],'linewidth',1.5 ,'markersize',2.5);
        
        if numel(abnormal_low)~=0 
            for i=1:numel(abnormal_low)
                plot(app.Score,abnormal_low(i),total(abnormal_low(i)),'o','MarkerSize',8,'MarkerEdgeColor','r','MarkerFaceColor',[1 .6 .6]);
            end
            app.fighting.Position=[449,117,284,146];
            app.fighting.Visible='on';
        else 
            app.Comfort_text.Position=[449,117,284,146];
            app.Comfort_text.Visible='on';
        end
        if numel(abnormal_high)~=0 
            for i=1:numel(abnormal_high)
                plot(app.Score,abnormal_high(i),total(abnormal_high(i)),'o','MarkerSize',8,'MarkerEdgeColor','g','MarkerFaceColor',[0 0.5 0.2]);
            end
        end
        
        % predicted grades line drawing
        predict_rank = transpose(prediction(:,7));
        plot(app.Score,numel(total):numel(total)+3, [total(end),predict_rank], 'Color', [0.9 0 0.8],'LineWidth',1.5,'markerSize',8);
        app.previous.Visible='on';
        app.predicted.Visible='on';
        
        % prediction of the university
        mean_predicted_rank = mean(predict_rank);
        if mean_predicted_rank>=95
            app.university.Value='Prediction: THU BKU';
        elseif mean_predicted_rank>=90
            app.university.Value='Prediction: SJTU FDU ZJU';
        elseif mean_predicted_rank>=85
            app.university.Value='Prediction: AU BU CU';
        elseif mean_predicted_rank>=80
            app.university.Value='Prediction: DU EU FU';
        elseif mean_predicted_rank>=75
            app.university.Value='Prediction: GU HU IU';
        elseif mean_predicted_rank>=70
            app.university.Value='Prediction: JU KU LU';
        elseif mean_predicted_rank>=65
            app.university.Value='Prediction: MU NU OU';
        elseif mean_predicted_rank>=60
            app.university.Value='Prediction: PU QU RU';
        elseif mean_predicted_rank>=55
            app.university.Value='Prediction: SU TU UU';
        elseif mean_predicted_rank>=50
            app.university.Value='Prediction: VU WU XU YU ZU';
        else
            app.university.Value='Prediction: below the admitted grades of key universities';
        end
        app.university.Visible='on';
        end

        % Button pushed function: TeacherButton
        function TeacherButtonPushed(app, event)
            % change the visibility in teacher page
            app.I_am.Visible='off';
            app.FreshmanButton.Visible='off';
            app.JuniorSeniorButton.Visible='off';
            app.TeacherButton.Visible='off';
            app.Welcome.Visible='off';
            app.BACKButton.Visible='on';
            app.gradeChoosingLabel.Visible='on';
            app.grade.Visible='on';
            app.TeacherStart.Visible='on';
            
        end

        % Button pushed function: TeacherStart
        function TeacherStartButtonPushed(app, event)
            % change the visibility in teacher page
            app.subjectChoosing.Visible='on';
            app.ButtonGroup.Visible='on';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.gradeChoosingLabel.Visible='off';
            app.grade.Visible='off';
            app.subjectChoosing.Visible='off';
            app.ButtonGroup.Visible='off';
            app.TeacherStart.Visible='off';

            
            global directory;
            % input the grade and subject
            switch app.grade.Value
                case 'freshman'
                    selected_grade = 1;
                    testID = 13;
                case 'junior'
                    selected_grade = 2;
                    testID = 27;
                otherwise
                    selected_grade = 3;
                    testID = 38;
            end
            switch selectedButton
                case app.ChineseButton
                    selectedSubject = 1;
                case app.MathButton
                    selectedSubject = 2;
                case app.EnglishButton
                    selectedSubject = 3;
                case app.PhysicsButton
                    selectedSubject = 4;
                case app.ChemistryButton
                    selectedSubject = 5;
                case app.BiologyButton
                    selectedSubject = 6;
                case app.HistoryButton
                    selectedSubject = 7;
                case app.GeographyButton
                    selectedSubject = 8;
                otherwise
                    selectedSubject = 17;
            end
            
            % find the students who are in progress or in decline
            [progress_, decline_] = fluctuation_range(directory,selected_grade,selectedSubject,testID);
            app.UITable.Data=averagegra(directory,selected_grade,selectedSubject,testID);
            i=1;
            progress_1=[];
            while i<=numel(progress_)
                progress_1 = [progress_1,num2str(progress_(i)),' '];
                i = i+1;
            end
            i=1;
            decline_1=[];
            while i<=numel(decline_)
                decline_1 = [decline_1,num2str(decline_(i)),' '];
                i = i+1;
            end
            if numel(progress_)~=0
               app.progress.Value = progress_1;
            else 
               app.progress.Value = 'NONE';
            end
            if numel(decline_)~=0
               app.decline.Value = decline_1;
            else
               app.decline.Value = 'NONE';
            end
            app.StudentswithDeclineLabel.Visible='on';
            app.StudentswithGreatProgressLabel.Visible='on';
            app.UITable.Visible='on';
            app.progress.Visible='on';
            app.decline.Visible = 'on';
        end

        % Button pushed function: DetailedScoreButton
        function DetailedScoreButtonPushed(app, event)
            % change the visibility of the objects in the detailed grades
            % page
            hold(app.Radar,'off');
            plot(app.Radar,1,1,'Color',[1 1 1]);
            hold(app.Score,'off');
            plot(app.Score,1,1,'Color',[1 1 1]);
            app.Radar.Visible='off';
            app.Score.Visible='off';
            app.STARTButton_F.Visible='off';
            app.StudentIDEditField1.Visible='off';
            app.PleasetypeinyourstudentIDLabel.Visible='off';
            app.CourseSelect_text.Visible='off';
            app.RecommendedCourseselectionPlanLabel.Visible='off';
            app.STARTButton_JS.Visible='off';
            app.StudentIDEditField2.Visible='off';
            app.fighting.Visible='off';
            app.Comfort_text.Visible='off';
            app.stability.Visible='off';
            app.average.Visible='off';
            app.previous.Visible='off';
            app.predicted.Visible='off';
            app.university.Visible='off';
            app.gradeChoosingLabel.Visible='off';
            app.grade.Visible='off';
            app.subjectChoosing.Visible='off';
            app.ButtonGroup.Visible='off';
            app.UITable.Visible='off';
            app.progress.Visible='off';
            app.decline.Visible = 'off';
            app.TeacherStart.Visible='off';
            app.StudentswithDeclineLabel.Visible='off';
            app.StudentswithGreatProgressLabel.Visible='off';
            app.DetailedScoreButton.Visible='off';
            app.detail.Visible='on';
            
            % read the data and show the corresponding grades of the
            % students in the table
            global directory;
            value = app.StudentIDEditField2.Value;
            [chi,mat,eng,phy,che,bio,pol,his,geo,~,total,~,~,~]=readdata(value, directory);
            app.detail.Data(1,1)=chi(end,2);
            app.detail.Data(2,1)=mat(end,2);
            app.detail.Data(3,1)=eng(end,1);
            app.detail.Data(4,1)=phy(end,1);
            app.detail.Data(5,1)=che(end,1);
            app.detail.Data(6,1)=bio(end,1);
            app.detail.Data(7,1)=geo(end,1);
            app.detail.Data(8,1)=his(end,1);
            app.detail.Data(9,1)=pol(end,1);
            app.detail.Data(10,1)=total(end,2);
            app.detail.Data(1,2)=chi(end,2);
            app.detail.Data(2,2)=mat(end,2);
            app.detail.Data(3,2)=eng(end,2);
            app.detail.Data(4,2)=phy(end,2);
            app.detail.Data(5,2)=che(end,2);
            app.detail.Data(6,2)=bio(end,2);
            app.detail.Data(7,2)=geo(end,2);
            app.detail.Data(8,2)=his(end,2);
            app.detail.Data(9,2)=pol(end,2);
            app.detail.Data(10,2)=total(end,2);
            app.detail.Data(1,3)=chi(end,6);
            app.detail.Data(2,3)=mat(end,6);
            app.detail.Data(3,3)=eng(end,6);
            app.detail.Data(4,3)=phy(end,6);
            app.detail.Data(5,3)=che(end,6);
            app.detail.Data(6,3)=bio(end,6);
            app.detail.Data(7,3)=geo(end,6);
            app.detail.Data(8,3)=his(end,6);
            app.detail.Data(9,3)=pol(end,6);
            app.detail.Data(10,3)=total(end,6);
            app.detail.Data(1,4)=chi(end,7);
            app.detail.Data(2,4)=mat(end,7);
            app.detail.Data(3,4)=eng(end,7);
            app.detail.Data(4,4)=phy(end,7);
            app.detail.Data(5,4)=che(end,7);
            app.detail.Data(6,4)=bio(end,7);
            app.detail.Data(7,4)=geo(end,7);
            app.detail.Data(8,4)=his(end,7);
            app.detail.Data(9,4)=pol(end,7);
            app.detail.Data(10,4)=total(end,7);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GradesManage = uifigure('Visible', 'off');
            app.GradesManage.Name = 'UI Figure';
            app.Radar = uiaxes(app.GradesManage);
            title(app.Radar, '')
            xlabel(app.Radar, 'X')
            ylabel(app.Radar, 'Y')
            app.Score = uiaxes(app.GradesManage);
            title(app.Score, '')
            xlabel(app.Score, 'Exams')
            ylabel(app.Score, 'Grades')
            app.STARTButton_F = uibutton(app.GradesManage, 'state');
            app.STARTButton_F.ValueChangedFcn = createCallbackFcn(app, @STARTButton_FValueChanged, true);
            app.STARTButton_F.Text = 'START';
            app.StudentIDEditField1 = uieditfield(app.GradesManage, 'numeric');
            app.StudentIDEditField1.Limits = [1000 1076];
            app.StudentIDEditField1.Tooltip = {'please input your student ID'};
            app.StudentIDEditField1.Value = 1001;
            app.Welcome = uitextarea(app.GradesManage);
            app.Welcome.Editable = 'off';
            app.Welcome.Value = {'Welcome to Grades Manage System!'};
            app.I_am = uilabel(app.GradesManage);
            app.I_am.Text = 'I   am';
            app.FreshmanButton = uibutton(app.GradesManage, 'push');
            app.FreshmanButton.ButtonPushedFcn = createCallbackFcn(app, @FreshmanButtonPushed, true);
            app.FreshmanButton.Text = 'Freshman';
            app.JuniorSeniorButton = uibutton(app.GradesManage, 'push');
            app.JuniorSeniorButton.ButtonPushedFcn = createCallbackFcn(app, @JuniorSeniorButtonPushed, true);
            app.JuniorSeniorButton.Text = 'Junior/Senior';
            app.TeacherButton = uibutton(app.GradesManage, 'push');
            app.TeacherButton.ButtonPushedFcn = createCallbackFcn(app, @TeacherButtonPushed, true);
            app.TeacherButton.Text = 'Teacher';
            app.PleasetypeinyourstudentIDLabel = uilabel(app.GradesManage);
            app.PleasetypeinyourstudentIDLabel.Text = 'Please type in your student ID:';
            app.CourseSelect_text = uitextarea(app.GradesManage);
            app.RecommendedCourseselectionPlanLabel = uilabel(app.GradesManage);
            app.RecommendedCourseselectionPlanLabel.Text = 'Recommended Course-selection Plan';
            app.BACKButton = uibutton(app.GradesManage, 'state');
            app.BACKButton.ValueChangedFcn = createCallbackFcn(app, @BACKButtonValueChanged, true);
            app.BACKButton.Text = 'BACK';
            app.STARTButton_JS = uibutton(app.GradesManage, 'state');
            app.STARTButton_JS.ValueChangedFcn = createCallbackFcn(app, @STARTButton_JSValueChanged, true);
            app.STARTButton_JS.Text = 'START';
            app.StudentIDEditField2 = uieditfield(app.GradesManage, 'numeric');
            app.StudentIDEditField2.Limits = [2001 3080];
            app.StudentIDEditField2.Tooltip = {'please input your student ID (2001-2080, 3001-3080)'};
            app.StudentIDEditField2.Value = 2001;
            app.Comfort_text = uitextarea(app.GradesManage);
            app.Comfort_text.Value = {'You are great now, but you can still try hard to be better!'};
            app.fighting = uiimage(app.GradesManage);
            app.fighting.ImageSource = 'comfort_picture.jpeg';
            app.stability = uilabel(app.GradesManage);
            app.stability.Text = 'red: stability';
            app.average = uilabel(app.GradesManage);
            app.average.Text = 'blue: average';
            app.previous = uilabel(app.GradesManage);
            app.previous.Text = {'blue: previous grades'; ''};
            app.predicted = uilabel(app.GradesManage);
            app.predicted.Text = 'pink: predicted grades';
            app.university = uitextarea(app.GradesManage);
            app.grade = uieditfield(app.GradesManage, 'text');
            app.gradeChoosingLabel = uilabel(app.GradesManage);
            app.gradeChoosingLabel.Text = 'Please type in the grade you are responsible for:';
            app.subjectChoosing = uilabel(app.GradesManage);
            app.subjectChoosing.Text = 'Please choose the subject you are responsible for:';
            app.ButtonGroup = uibuttongroup(app.GradesManage);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ChineseButton = uitogglebutton(app.ButtonGroup);
            app.ChineseButton.Text = 'Chinese';
            app.ChineseButton.Value = true;
            app.MathButton = uitogglebutton(app.ButtonGroup);
            app.MathButton.Text = 'Math';
            app.EnglishButton = uitogglebutton(app.ButtonGroup);
            app.EnglishButton.Text = 'English';
            app.PhysicsButton = uitogglebutton(app.ButtonGroup);
            app.PhysicsButton.Text = 'Physics';
            app.ChemistryButton = uitogglebutton(app.ButtonGroup);
            app.ChemistryButton.Text = 'Chemistry';
            app.BiologyButton = uitogglebutton(app.ButtonGroup);
            app.BiologyButton.Text = 'Biology';
            app.HistoryButton = uitogglebutton(app.ButtonGroup);
            app.HistoryButton.Text = 'History';
            app.GeographyButton = uitogglebutton(app.ButtonGroup);
            app.GeographyButton.Text = 'Geography';
            app.PoliticsButton = uitogglebutton(app.ButtonGroup);
            app.PoliticsButton.Text = 'Politics';
            app.TeacherStart = uibutton(app.GradesManage, 'push');
            app.TeacherStart.ButtonPushedFcn = createCallbackFcn(app, @TeacherStartButtonPushed, true);
            app.TeacherStart.Text = 'START';
            app.UITable = uitable(app.GradesManage);
            app.UITable.ColumnName = {'Mean'; 'Q1'; 'Q2'; 'Q3'; 'stdValue'; 'varValue'; 'high-low'};
            app.UITable.RowName = {''};
            app.progress = uitextarea(app.GradesManage);
            app.decline = uitextarea(app.GradesManage);
            app.StudentswithGreatProgressLabel = uilabel(app.GradesManage);
            app.StudentswithGreatProgressLabel.Text = 'Students with Great Progress:';
            app.StudentswithDeclineLabel = uilabel(app.GradesManage);
            app.StudentswithDeclineLabel.Text = 'Students with Decline:';
            app.DetailedScoreButton = uibutton(app.GradesManage, 'push');
            app.DetailedScoreButton.ButtonPushedFcn = createCallbackFcn(app, @DetailedScoreButtonPushed, true);
            app.DetailedScoreButton.Text = 'Detailed Score';
            app.detail = uitable(app.GradesManage);
            app.detail.ColumnName = {'Original Score'; 'Conveted Score'; 'Ranking'; 'Percentage(%)'};
            app.detail.RowName = {'Chinese'; 'Math'; 'English'; 'Physics'; 'Chemistry'; 'Biology'; 'Geography'; 'History'; 'Politics'; 'Total'};
            % Show the figure after all components are created
            app.GradesManage.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GradesManage)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GradesManage)
        end
    end
end